classdef FinanceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FigureGrid            matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        SummaryTab            matlab.ui.container.Tab
        SummaryGrid           matlab.ui.container.GridLayout
        PreTaxVoluntaryTab    matlab.ui.container.Tab
        PreTaxVoluntaryGrid   matlab.ui.container.GridLayout
        PreTaxCompulsoryTab   matlab.ui.container.Tab
        PreTaxCompulsoryGrid  matlab.ui.container.GridLayout
        PostTaxTab            matlab.ui.container.Tab
        PostTaxGrid           matlab.ui.container.GridLayout
        SettingsTab           matlab.ui.container.Tab
        SettingsGrid          matlab.ui.container.GridLayout
        CopyrightLabel        matlab.ui.control.Label
        GitHubLabel           matlab.ui.control.Label
        SpinnerLabel          matlab.ui.control.Label
        Spinner               matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        % Finance model computing net income from gross income.
        Model (1, 1)
        % Array of controllers used to edit model data.
        Controllers (1, :) element.Component = element.Null
        % Array of view-controllers used to visualize/edit deductions.
        Hybrids (1, :) element.Component = element.Null
        % Array of views used to visualize deductions.
        Views (1, :) element.Component = element.Null
    end % properties (Access = private)
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check MATLAB release.
            if verLessThan("MATLAB", "9.8")
                app.delete();
                error("FinanceApp:MATLAB:TooOld", ...
                    "MATLAB R2020a or later is required to run this app.")
            end
            
            % Import all MVC elements.
            import element.view.*
            import element.control.*
            import element.hybrid.*

            % Hide figure unTIL all components are created.
            app.UIFigure.Visible = "off";
            
            % Create finance model.
            app.Model = Finance();
                        
            % Create income view-controller.
            app.Hybrids(1) = IncomeViewController(app.Model, ...
                "Parent", app.FigureGrid);
            app.Hybrids(1).Layout.Row = 1;
            app.Hybrids(1).Layout.Column = [1, 2];
            
            % Create summary view.
            app.Views(1) = SummaryView(app.Model, ...
                "Parent", app.SummaryGrid);
            
            % Crete deduction views.
            app.Views(2) = DeductionsView(app.Model, ...
                "Parent", app.PreTaxVoluntaryGrid, ...
                "TrackedProperty", "PreTax", ...
                "ColumnEditable", false, ...
                "ColumnSortable", [false, true, false, false]);
            
            app.Views(3) = DeductionsView(app.Model, ...
                "Parent", app.PreTaxCompulsoryGrid, ...
                "TrackedProperty", "TaxNITable", ...
                "ColumnEditable", false, ...
                "ColumnSortable", [false, true, false, false]);
            
            app.Views(4) = DeductionsView(app.Model, ...
                "Parent", app.PostTaxGrid, ...
                "TrackedProperty", "PostTax", ...
                "ColumnEditable", false, ...
                "ColumnSortable", [false, true, false, false]);
            
            % Create recurrence controllers.
            app.Controllers(1) = RecurrenceController(app.Model, ...
                "Parent", app.FigureGrid, ...
                "Width", app.UIFigure.Position(3) - 40); % subtract padding
            app.Controllers(1).Layout.Row = 2;
            app.Controllers(1).Layout.Column = [1, 2];
            
            % Create deduction controllers.
            app.Controllers(2) = DeductionsController(app.Model, ...
                "Parent", app.UIFigure, ...
                "TrackedProperty", "PreTax");
            app.Views(2).ContextMenu = app.Controllers(2).UIContextMenu;
            
            app.Controllers(3) = DeductionsController(app.Model, ...
                "Parent", app.UIFigure, ...
                "TrackedProperty", "PostTax");
            app.Views(4).ContextMenu = app.Controllers(3).UIContextMenu;
            
            % Create settings view-controller.
            app.Hybrids(2) = SettingsViewController(app.Model, ...
                "Parent", app.SettingsGrid);

            % Show figure after all components are created.
            app.UIFigure.Visible = "on";
            
            % Call currency controller.
            CurrencyController(app.Model, ...
                "Parent", app.FigureGrid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Income Finance App';
            app.FigureGrid = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.FigureGrid);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.SummaryGrid = uigridlayout(app.SummaryTab);
            app.PreTaxVoluntaryTab = uitab(app.TabGroup);
            app.PreTaxVoluntaryTab.Title = 'Pre-Tax Voluntary';
            app.PreTaxVoluntaryGrid = uigridlayout(app.PreTaxVoluntaryTab);
            app.PreTaxCompulsoryTab = uitab(app.TabGroup);
            app.PreTaxCompulsoryTab.Title = 'Pre-Tax Compulsory';
            app.PreTaxCompulsoryGrid = uigridlayout(app.PreTaxCompulsoryTab);
            app.PostTaxTab = uitab(app.TabGroup);
            app.PostTaxTab.Title = 'Post-Tax';
            app.PostTaxGrid = uigridlayout(app.PostTaxTab);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsGrid = uigridlayout(app.SettingsTab);
            app.CopyrightLabel = uilabel(app.FigureGrid);
            app.CopyrightLabel.Text = 'Copyright © Michele Facchinelli 2019-2020';
            app.GitHubLabel = uilabel(app.FigureGrid);
            app.GitHubLabel.Text = 'mfacchinelli.github.io';
            app.SpinnerLabel = uilabel(app.FigureGrid);
            app.SpinnerLabel.Visible = 'off';
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.FigureGrid);
            app.Spinner.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinanceApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end